classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LandingTab                   matlab.ui.container.Tab
        OBJECTSHAPERECOGNITIONLabel  matlab.ui.control.Label
        TEAMMEMBERSLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        GUIDELabel                   matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        WorkingTab                   matlab.ui.container.Tab
        TrainingButton               matlab.ui.control.Button
        SelectImageButton            matlab.ui.control.Button
        EdgeDetectButton             matlab.ui.control.Button
        GeometricButton              matlab.ui.control.Button
        SIFTButton                   matlab.ui.control.Button
        DetectButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        OriginalImageLabel           matlab.ui.control.Label
        EdgeDetectedLabel            matlab.ui.control.Label
        InitialiseButton             matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Switch                       matlab.ui.control.Switch
        MinorAxisEditFieldLabel      matlab.ui.control.Label
        MinorAxisEditField           matlab.ui.control.NumericEditField
        MajorAxisEditFieldLabel      matlab.ui.control.Label
        MajorAxisEditField           matlab.ui.control.NumericEditField
        PerimeterEditFieldLabel      matlab.ui.control.Label
        PerimeterEditField           matlab.ui.control.NumericEditField
        AreaEditFieldLabel           matlab.ui.control.Label
        AreaEditField                matlab.ui.control.NumericEditField
        CheckBox                     matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            global featureVector;
            global SVMModel;
            featureVector=training();
            SVMModel=svmconstruct(featureVector);
            app.CheckBox.Value=1;

        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            warning('off');


% reading input image %
[filename,pathname] = uigetfile({'*.jpg';'*.png';'*.tif';'*.gif'});
global I;
I = imread([pathname,filename]);
Ir=imresize(I,[600 1100]);
imshow(Ir,'Parent',app.UIAxes);

        end

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            clc;
            close all;
            init=imread('F:/final year project/object-recognition/Images/init.jpg');
            init=imresize(init,[600 1100]);
            imshow(init,'Parent',app.UIAxes);
            imshow(init,'Parent',app.UIAxes_2);
            imshow(init,'Parent',app.UIAxes_3);
            app.Switch.Value='Shape Extracted';
            app.AreaEditField.Value=0;
            app.MinorAxisEditField.Value=0;
            app.MajorAxisEditField.Value=0;
            app.PerimeterEditField.Value=0;

            
        end

        % Button pushed function: EdgeDetectButton
        function EdgeDetectButtonPushed(app, event)
            global I;
            [m,n,o]=size(I);
            if o==3
        G=rgb2gray(I);
            else 
            G=I;
            end
            adj=imadjust(G);
            bi=G<180;
            can=edge(bi,'canny');
            sob=edge(bi,'sobel');
            global comb;
            comb=or(can,sob);
            combr=imresize(comb,[600 1100]);
            imshow(combr,'Parent',app.UIAxes_2);
            [B,L] = bwboundaries(comb);
            figure,imshow(I);
            title("edge detection marked");
            hold on
for k = 1:length(B)
    boundary = B{k};
    plot(boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2)
end
global combh;


combh=imfill(bi,'holes');
 combhr=imresize(comb,[600 1100]);
imshow(combhr,'Parent',app.UIAxes_3);
        end

        % Button pushed function: SIFTButton
        function SIFTButtonPushed(app, event)
            global comb;
            global kpmag;
            [kp,kpl,kpori,kpmag,kpd,Fig]=SIFT(comb);
            app.Switch.Value='SIFT Feature';
            figr=imresize(Fig,[600 1100]);
            imshow(figr,'Parent',app.UIAxes_3);
        end

        % Button pushed function: GeometricButton
        function GeometricButtonPushed(app, event)
            global combh;
            g=regionprops(combh,'all');
g1=extractfield(g,'Area');
[g11,index1]=max(g1);
ar=round(g11);

g2=extractfield(g,'MajorAxisLength');
[g22,index2]=max(g2);
maj=round(g22);

g3=extractfield(g,'MinorAxisLength');
[g33,index3]=max(g3);
min=round(g33);

g4=extractfield(g,'Perimeter');
[g44,index4]=max(g4);
per=round(g44);
global featureout;
featureout=[ar,maj,min,per];
app.AreaEditField.Value=featureout(1);
app.MinorAxisEditField.Value=featureout(3);
app.MajorAxisEditField.Value=featureout(2);
app.PerimeterEditField.Value=featureout(4);

        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            global featureout;
            global kpmag;
            global featureVector;
            global SVMModel;
            featuretest=[];
            kp=kpmag(1:50);
            %% Construct the Feature Vector%%
featuretest=[featuretest;[ kp' featureout]];

TestSet1 = featuretest;
disp(TestSet1);
[label,score] = predict(SVMModel,TestSet1);
if label==1
    msgbox('APPLE');
elseif label==2
    msgbox('LEAF');
    else
        msgbox('NOT RECOGNISED');
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LandingTab = uitab(app.TabGroup);
            app.LandingTab.Title = 'Landing';
            app.OBJECTSHAPERECOGNITIONLabel = uilabel(app.LandingTab);
            app.OBJECTSHAPERECOGNITIONLabel.Text = 'OBJECT SHAPE RECOGNITION';
            app.TEAMMEMBERSLabel = uilabel(app.LandingTab);
            app.TEAMMEMBERSLabel.Text = 'TEAM MEMBERS';
            app.TextArea = uitextarea(app.LandingTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Chandana Murthy'; 'Deepak V Kashyap'; 'Komal Rathod'; 'Nagarjun T N'};
            app.GUIDELabel = uilabel(app.LandingTab);
            app.GUIDELabel.Text = 'GUIDE';
            app.TextArea_2 = uitextarea(app.LandingTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Dr. Sahana D Gowda'; 'Professor and Head'; 'Dept of CSE'; 'BNMIT'};
            app.WorkingTab = uitab(app.TabGroup);
            app.WorkingTab.Title = 'Working';
            app.TrainingButton = uibutton(app.WorkingTab, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.SelectImageButton = uibutton(app.WorkingTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.EdgeDetectButton = uibutton(app.WorkingTab, 'push');
            app.EdgeDetectButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectButtonPushed, true);
            app.EdgeDetectButton.Text = 'Edge Detect';
            app.GeometricButton = uibutton(app.WorkingTab, 'push');
            app.GeometricButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricButtonPushed, true);
            app.GeometricButton.Text = 'Geometric';
            app.SIFTButton = uibutton(app.WorkingTab, 'push');
            app.SIFTButton.ButtonPushedFcn = createCallbackFcn(app, @SIFTButtonPushed, true);
            app.SIFTButton.Text = 'SIFT';
            app.DetectButton = uibutton(app.WorkingTab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.UIAxes = uiaxes(app.WorkingTab);
            app.UIAxes_2 = uiaxes(app.WorkingTab);
            app.UIAxes_3 = uiaxes(app.WorkingTab);
            app.OriginalImageLabel = uilabel(app.WorkingTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.EdgeDetectedLabel = uilabel(app.WorkingTab);
            app.EdgeDetectedLabel.Text = 'Edge Detected';
            app.InitialiseButton = uibutton(app.WorkingTab, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.Label = uilabel(app.WorkingTab);
            app.Label.Text = '';
            app.Switch = uiswitch(app.WorkingTab, 'slider');
            app.Switch.Items = {'SIFT Feature', 'Shape Extracted'};
            app.Switch.Value = 'Shape Extracted';
            app.MinorAxisEditFieldLabel = uilabel(app.WorkingTab);
            app.MinorAxisEditFieldLabel.Text = 'Minor Axis';
            app.MinorAxisEditField = uieditfield(app.WorkingTab, 'numeric');
            app.MajorAxisEditFieldLabel = uilabel(app.WorkingTab);
            app.MajorAxisEditFieldLabel.Text = 'Major Axis';
            app.MajorAxisEditField = uieditfield(app.WorkingTab, 'numeric');
            app.PerimeterEditFieldLabel = uilabel(app.WorkingTab);
            app.PerimeterEditFieldLabel.Text = 'Perimeter';
            app.PerimeterEditField = uieditfield(app.WorkingTab, 'numeric');
            app.AreaEditFieldLabel = uilabel(app.WorkingTab);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.WorkingTab, 'numeric');
            app.CheckBox = uicheckbox(app.WorkingTab);
            app.CheckBox.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end